classdef FreeSpaceOpticalCommunicationsApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FSOCSystemSimulatorUIFigure    matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        TransmitterTab                 matlab.ui.container.Tab
        AverageTransmittedPower        matlab.ui.control.NumericEditField
        TransmitedPowermWLabel         matlab.ui.control.Label
        CalculateButton                matlab.ui.control.Button
        Modulation                     matlab.ui.control.DropDown
        ModulationDropDownLabel        matlab.ui.control.Label
        AtmosphericConditions          matlab.ui.control.DropDown
        AtmosphericConditionsLabel     matlab.ui.control.Label
        ThetaUnits                     matlab.ui.control.DropDown
        Theta                          matlab.ui.control.NumericEditField
        BeamDivergenceLabel            matlab.ui.control.Label
        Apperture                      matlab.ui.control.EditField
        ApperturecmLabel               matlab.ui.control.Label
        BitRateUnits                   matlab.ui.control.DropDown
        BitRate                        matlab.ui.control.NumericEditField
        RequiredbitrateEditFieldLabel  matlab.ui.control.Label
        LinkLengthkm                   matlab.ui.control.NumericEditField
        LinkLengthkmEditFieldLabel     matlab.ui.control.Label
        Wavelength                     matlab.ui.control.NumericEditField
        WavelengthnmEditFieldLabel     matlab.ui.control.Label
        WhereDropDown                  matlab.ui.control.DropDown
        WhereDropDownLabel             matlab.ui.control.Label
        TransmittedMessage             matlab.ui.control.EditField
        EntermessagetobetransmittedLabel  matlab.ui.control.Label
        TransmitterReceiver            matlab.ui.container.TabGroup
        Transmitter2Receiver           matlab.ui.container.Tab
        YAxismisalignmentdegreesSlider_default  matlab.ui.control.Slider
        XAxismisalignmentdegreesSlider_default  matlab.ui.control.Slider
        Transmitter2ReceiverPlot       matlab.ui.control.UIAxes
        two_stage_transmission         matlab.ui.container.TabGroup
        Transmitter2satTab             matlab.ui.container.Tab
        YAxismisalignmentdegreesSlider_1st_stage  matlab.ui.control.Slider
        XAxismisalignmentdegreesSlider_1st_stage  matlab.ui.control.Slider
        Transmitter2SatPlot            matlab.ui.control.UIAxes
        Sat2receiverTab                matlab.ui.container.Tab
        YAxismisalignmentdegreesSlider_2nd_stage  matlab.ui.control.Slider
        XAxismisalignmentdegreesSlider_2nd_stage  matlab.ui.control.Slider
        Sat2ReceiverPlot               matlab.ui.control.UIAxes
        ReceiverTab                    matlab.ui.container.Tab
        TabGroup3                      matlab.ui.container.TabGroup
        BERvsSNRTab                    matlab.ui.container.Tab
        BERvsSNR                       matlab.ui.control.UIAxes
        BERvsQTab                      matlab.ui.container.Tab
        BERvsQ                         matlab.ui.control.UIAxes
        CapacityvsBandwidthTab         matlab.ui.container.Tab
        CAPACITYvsSNR                  matlab.ui.control.UIAxes
        BacktotransmitterButton        matlab.ui.control.Button
        QualityFactor                  matlab.ui.control.NumericEditField
        SNREditFieldLabel_2            matlab.ui.control.Label
        SNR                            matlab.ui.control.NumericEditField
        SNREditFieldLabel              matlab.ui.control.Label
        BER                            matlab.ui.control.NumericEditField
        BEREditFieldLabel              matlab.ui.control.Label
        Receivedback                   matlab.ui.control.EditField
        ReceivedbackLabel              matlab.ui.control.Label
        PointingErrors                 matlab.ui.control.NumericEditField
        PointingErrorsdBLabel          matlab.ui.control.Label
        GeometricalLosses              matlab.ui.control.NumericEditField
        GeometricalLossesdBLabel       matlab.ui.control.Label
        AtmosphericAttenuation         matlab.ui.control.NumericEditField
        AtmosphericAttenuationEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        % transmitted % Description
    end
    methods (Access = public)
        function plotter(app,binary_output,sent_binary,BW, ...
                mc,modulation)
            % [~,measured_bit_error_rate]=biterr(sent_binary,binary_output);
            % measured_bit_error_rate= measured_bit_error_rate*ones(1,length(mc.snr));
            % snrdb=1:9/length(binary_output):10-(9/length(binary_output));
            % snrlin=10.^(mc.snr./10);
            theoretical_ber=0.5.*erfc(sqrt(mc.snr));
            % -------------------------------------------------------------
            % BER vs SNR
            semilogy(app.BERvsSNR,mc.snr,mc.ber,'-bo',mc.snr,theoretical_ber,'-mh');
            legend(app.BERvsSNR,"measured","thoretical");
            title_text= [modulation, ' modulation with AWGN for SNR: ' ,num2str(min(mc.snr)),' to ',num2str(max(mc.snr)), ' dB'];
            title(app.BERvsSNR, title_text);
            xlabel(app.BERvsSNR,'Eb/No - SNR (dB)');
            ylabel(app.BERvsSNR,'Bit error rate');
            % -------------------------------------------------------------
            % BER vs Q
            MC_quality_factor= sqrt(2)*erfcinv(2*mc.ber);
            theoretical_quality_factor=sqrt(2)*erfcinv(2*theoretical_ber);
            semilogy(app.BERvsQ, MC_quality_factor,mc.ber,'-bo',...
                theoretical_quality_factor,theoretical_ber,'-mh');
            title_text= ['Quality factor plot for ',modulation,' modulation with AWGN for SNR: ',num2str(min(mc.snr)), ' to ',num2str(max(mc.snr)),' dB'];
            title(app.BERvsQ,title_text);
            xlabel(app.BERvsQ,'Quality Factor (Q)');
            ylabel(app.BERvsQ,'Bit error rate (BER)');
            legend(app.BERvsQ, "measured","theoretical");
            % -------------------------------------------------------------
            % Capacity of the system
            capacity=2*BW*log2(1+mc.snr); % varying power - not BR
            % capacity=capacity/1e9;
            semilogy(app.CAPACITYvsSNR,mc.snr,capacity);
            title_text= ['Capacity of the system for ',modulation,' modulation for variable power'];
            title(app.CAPACITYvsSNR,title_text);
            xlabel(app.CAPACITYvsSNR,'Eb/No - SNR (dB)');
            ylabel(app.CAPACITYvsSNR,'Capacity (C) in Gbits/second');
        end
        % -----------------------------------------------------------------
        % convert text to binary
        function doubleString = textToBinary(app,text_input)
            % Convert character to ASCII values & then binary
            binaryCell = cellstr(dec2bin(double(text_input), 8));
            % Concatenate binary strings
            binaryString = strcat(binaryCell{:});
            for i=1:length(binaryString)
                doubleString(i) = str2double(binaryString(i));
            end
        end
        % -----------------------------------------------------------------
        % convert binary back to text
        function textResult = binaryToText(app,demodulated_signal)
            charString= num2str(demodulated_signal);
            charString= strcat(charString(:))';
            binarySegments = reshape(charString, 8, length(charString)/8)';
            binarySegmentsCell = arrayfun(@(row) num2str(binarySegments(row, :)), 1:size(binarySegments, 1), 'UniformOutput', false);
            decimalValues = bin2dec(binarySegmentsCell);
            textResult = char(decimalValues');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            % Inputs
            % fig = uifigure;
            d = uiprogressdlg(app.FSOCSystemSimulatorUIFigure,'Title','Simulating...',...
                'Message','Loading your values....');
            pause(.5)
           
            text = app.TransmittedMessage.Value;
            binary_input=textToBinary(app,text);
            transmitter.wavelength = app.Wavelength.Value*1e-9;
            modulation = app.Modulation.Value;
            link.location = app.WhereDropDown.Value;
            link.length=app.LinkLengthkm.Value;
            BR=app.BitRate.Value;
            switch link.location
                case "Ground only"
                    x1=app.XAxismisalignmentdegreesSlider_default.Value;
                    y1=app.YAxismisalignmentdegreesSlider_default.Value;
                    x2=0;
                    y2=0;
                case "Cosmic Space only"
                    x1=app.XAxismisalignmentdegreesSlider_default.Value;
                    y1=app.YAxismisalignmentdegreesSlider_default.Value;
                    x2=0;
                    y2=0;
                otherwise % Ground via sat relay || Cosmic Space from earth
                    x1=app.XAxismisalignmentdegreesSlider_1st_stage.Value;
                    y1=app.YAxismisalignmentdegreesSlider_1st_stage.Value;
                    x2=app.XAxismisalignmentdegreesSlider_2nd_stage.Value;
                    y2=app.YAxismisalignmentdegreesSlider_2nd_stage.Value;
            end
            transmitter.misaligment=[x1,y1,x2,y2];
            switch app.BitRateUnits.Value
                case "kbps"
                    link.BR=1e3*BR;
                case "Mbps"
                    link.BR=1e6*BR;
                case "Gbps"
                    link.BR=1e9*BR;
                case "Tbps"
                    link.BR=1e12*BR;
            end
            transmitter.apperture=str2double(app.Apperture.Value);                     % made a lil oopsie by inserting a text field
            transmitter.beam_divergence=app.Theta.Value;
            if app.ThetaUnits.Value=="rad"
                transmitter.beam_divergence=transmitter.beam_divergence*180/pi;
            end
            link.atm_conditions= app.AtmosphericConditions.Value;
            transmitter.av_trans_power= app.AverageTransmittedPower.Value;
            d.Value = .33; 
            d.Message = 'Calculating....';
            pause(1)
            [binary_output,ber,snr,total_losses,BW] = fso_simulation(binary_input, modulation,link,transmitter); 
            % Outputs
            text_output = binaryToText(app,binary_output);
            app.Receivedback.Value=text_output;
            app.BER.Value=ber;
            app.SNR.Value=snr;
            if snr<0
                app.QualityFactor.Value=0;
            else
                app.QualityFactor.Value=sqrt(snr)/2;
            end
            app.AtmosphericAttenuation.Value=total_losses(1);
            app.GeometricalLosses.Value=total_losses(2);
            app.PointingErrors.Value=total_losses(3);

            d.Value = .67;
            d.Message = 'Performing Monte Carlo, this may take a moment.... ';
            pause(1)

            mc = monte_carlo(binary_input,link,BW,modulation,transmitter);
            
            d.Value = 0.9;
            d.Message = 'Writing values and plotting....';
            pause(1)
            app.TabGroup.SelectedTab = app.ReceiverTab;
            % Plotting
            plotter(app,binary_output,binary_input,BW,mc,modulation);
            d.Value=1;
            d.Message = 'Finishing up....';
            pause(1)
            close(d)
        end

        % Value changed function: WhereDropDown
        function WhereDropDownValueChanged(app, event)
            % Visibility toggle
            switch app.WhereDropDown.Value 
                case "Ground via sat relay"
                    app.LinkLengthkm.Visible ="off";
                    app.AtmosphericConditions.Visible="on";
                    app.AtmosphericConditionsLabel.Visible="on";
                    app.LinkLengthkmEditFieldLabel.Visible = "off";
                    app.TransmitterReceiver.Visible="off";
                    app.Transmitter2SatPlot.Color=[0.30,0.75,0.93];
                    app.Transmitter2SatPlot.GridColor='k';
                    app.Sat2ReceiverPlot.Color=[0.30,0.75,0.93];
                    app.Sat2ReceiverPlot.GridColor='k';
                    app.two_stage_transmission.Visible="on";
                case "Cosmic Space from earth"
                    app.LinkLengthkm.Visible="on";
                    app.AtmosphericConditions.Visible="on";
                    app.AtmosphericConditionsLabel.Visible="on";
                    app.LinkLengthkmEditFieldLabel.Visible = "on";
                    app.TransmitterReceiver.Visible="off";
                    app.Transmitter2SatPlot.Color=[0.30,0.75,0.93];
                    app.Transmitter2SatPlot.GridColor='k';
                    app.Sat2ReceiverPlot.Color=[0,0,0];
                    app.Sat2ReceiverPlot.GridColor='w';
                    app.two_stage_transmission.Visible="on";
                case "Cosmic Space only"
                    app.LinkLengthkm.Visible="on";
                    app.AtmosphericConditions.Visible="off";
                    app.AtmosphericConditionsLabel.Visible="off";
                    app.AtmosphericConditionsLabel.Visible="off";
                    app.LinkLengthkmEditFieldLabel.Visible = "on";
                    app.TransmitterReceiver.Visible="on";
                    app.Transmitter2ReceiverPlot.Color=[0,0,0];
                    app.Transmitter2ReceiverPlot.GridColor='w';
                    app.two_stage_transmission.Visible="off";
                otherwise % ground only
                    app.LinkLengthkm.Visible="on";                    
                    app.AtmosphericConditions.Visible="on";
                    app.AtmosphericConditionsLabel.Visible="on";
                    app.LinkLengthkmEditFieldLabel.Visible = "on";
                    app.TransmitterReceiver.Visible="on";
                    app.Transmitter2ReceiverPlot.Color=[0.30,0.75,0.93];
                    app.Transmitter2ReceiverPlot.GridColor='k';
                    app.two_stage_transmission.Visible="off";
            end
        end

        % Button pushed function: BacktotransmitterButton
        function BacktotransmitterButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.TransmitterTab;
        end

        % Value changed function: XAxismisalignmentdegreesSlider_default, 
        % ...and 1 other component
        function YAxismisalignmentdegreesSlider_defaultValueChanged(app, event)
            %% PLOTS
            changed_Value_x_axis = app.XAxismisalignmentdegreesSlider_default.Value;
            x_axis_movement=(sin(changed_Value_x_axis*pi/180)*app.LinkLengthkm.Value);
            changed_Value_y_axis = app.YAxismisalignmentdegreesSlider_default.Value;
            y_axis_movement=(sin(changed_Value_y_axis*pi/180)*app.LinkLengthkm.Value);
            p=plot(app.Transmitter2ReceiverPlot,0,0,'w', ...
                x_axis_movement, ...
                y_axis_movement,'r');
            legend(app.Transmitter2ReceiverPlot,"Receiving","Transmitting",Textcolor='w',Box="off");
            %% PLOTTER magic
            p(1).Marker="o";
            p(1).MarkerSize=3;
            p(1).MarkerFaceColor="w";
            p(2).Marker="o";
            p(2).MarkerSize=20;
            p(2).MarkerFaceColor="none";
            %% LIMITS 
            zoomout_percentage=1.1;
            allowance=2;
            ylimit1=-abs(y_axis_movement)*zoomout_percentage-allowance;
            ylimit2=abs(y_axis_movement)*zoomout_percentage+allowance;
            ylim(app.Transmitter2ReceiverPlot,[ylimit1,ylimit2]);
            xlimit1=-abs(x_axis_movement)*zoomout_percentage-allowance;
            xlimit2=abs(x_axis_movement)*zoomout_percentage+allowance;
            xlim(app.Transmitter2ReceiverPlot,[xlimit1,xlimit2]);
        end

        % Value changed function: 
        % XAxismisalignmentdegreesSlider_1st_stage, 
        % ...and 1 other component
        function YAxismisalignmentdegreesSlider_1st_stageValueChanged(app, event)
            %% PLOTS
            changed_Value_x_axis = app.XAxismisalignmentdegreesSlider_1st_stage.Value;
            x_axis_movement=(sin(changed_Value_x_axis*pi/180)*app.LinkLengthkm.Value);
            changed_Value_y_axis = app.YAxismisalignmentdegreesSlider_1st_stage.Value;
            y_axis_movement =(sin(changed_Value_y_axis*pi/180)*app.LinkLengthkm.Value);
            p=plot(app.Transmitter2SatPlot,0,0,'w', ...
                x_axis_movement, ...
                y_axis_movement,'r');
            legend(app.Transmitter2SatPlot,"Receiving","Transmitting",Textcolor='w',Box="off");
            %% PLOTTER magic
            p(1).Marker="o";
            p(1).MarkerSize=3;
            p(1).MarkerFaceColor="w";
            p(2).Marker="o";
            p(2).MarkerSize=20;
            p(2).MarkerFaceColor="none";
            %% LIMITS
            zoomout_percentage=1.1;
            allowance=2;
            ylimit1=-abs(y_axis_movement)*zoomout_percentage-allowance;
            ylimit2=abs(y_axis_movement)*zoomout_percentage+allowance;
            ylim(app.Transmitter2SatPlot,[ylimit1,ylimit2]);
            xlimit1=-abs(x_axis_movement)*zoomout_percentage-allowance;
            xlimit2=abs(x_axis_movement)*zoomout_percentage+allowance;
            xlim(app.Transmitter2SatPlot,[xlimit1,xlimit2]);
        end

        % Value changed function: 
        % XAxismisalignmentdegreesSlider_2nd_stage, 
        % ...and 1 other component
        function YAxismisalignmentdegreesSlider_2nd_stageValueChanged(app, event)
            %% PLOTS
            changed_Value_x_axis = app.XAxismisalignmentdegreesSlider_2nd_stage.Value;
            x_axis_movement =sin(changed_Value_x_axis*pi/180)*app.LinkLengthkm.Value;
            changed_Value_y_axis = app.YAxismisalignmentdegreesSlider_2nd_stage.Value;
            y_axis_movevent =(sin(changed_Value_y_axis*pi/180)*app.LinkLengthkm.Value);
            p=plot(app.Sat2ReceiverPlot,0,0,'w', ...
                x_axis_movement, ...
                y_axis_movevent,'r');
            legend(app.Sat2ReceiverPlot,"Receiving","Transmitting",Textcolor='w',Box="off");
            %% PLOTTER magic
            p(1).Marker="o";
            p(1).MarkerSize=3;
            p(1).MarkerFaceColor="w";
            p(2).Marker="o";
            p(2).MarkerSize=20;
            p(2).MarkerFaceColor="none";
            %% LIMITS
            zoomout_percentage=1.1;
            allowance=2;
            ylimit1=-abs(y_axis_movevent)*zoomout_percentage-allowance;
            ylimit2=abs(y_axis_movevent)*zoomout_percentage+allowance;
            ylim(app.Sat2ReceiverPlot,[ylimit1,ylimit2]);
            xlimit1=-abs(x_axis_movement)*zoomout_percentage-allowance;
            xlimit2=abs(x_axis_movement)*zoomout_percentage+allowance;
            xlim(app.Sat2ReceiverPlot,[xlimit1,xlimit2]);
        end

        % Value changed function: LinkLengthkm
        function LinkLengthkmValueChanged(app, event)
            % value = app.LinkLengthkm.Value;
            YAxismisalignmentdegreesSlider_defaultValueChanged(app, event);
            YAxismisalignmentdegreesSlider_1st_stageValueChanged(app, event);
            YAxismisalignmentdegreesSlider_2nd_stageValueChanged(app, event);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.FSOCSystemSimulatorUIFigure = uifigure('Visible', 'off');
            app.FSOCSystemSimulatorUIFigure.Name = 'FSOC System Simulator';
            app.FSOCSystemSimulatorUIFigure.Icon = fullfile(pathToMLAPP, 'laser.png');
            app.TabGroup = uitabgroup(app.FSOCSystemSimulatorUIFigure);
            app.TransmitterTab = uitab(app.TabGroup);
            app.TransmitterTab.Tooltip = {''};
            app.TransmitterTab.Title = 'Transmitter';
            app.two_stage_transmission = uitabgroup(app.TransmitterTab);
            app.Transmitter2satTab = uitab(app.two_stage_transmission);
            app.Transmitter2satTab.Title = 'Transmitter to sat';
            app.Transmitter2SatPlot = uiaxes(app.Transmitter2satTab);
            title(app.Transmitter2SatPlot, '1st stage misalignment (in km)')
            xlabel(app.Transmitter2SatPlot, 'X Axis misalignment (degrees)')
            ylabel(app.Transmitter2SatPlot, 'X Axis misalignment (degrees)')
            zlabel(app.Transmitter2SatPlot, 'Z')
            app.XAxismisalignmentdegreesSlider_1st_stage = uislider(app.Transmitter2satTab);
            app.XAxismisalignmentdegreesSlider_1st_stage.Limits = [-45 45];
            app.XAxismisalignmentdegreesSlider_1st_stage.MajorTicks = [-60 -45 -30 -15 0 15 30 45 60];
            app.XAxismisalignmentdegreesSlider_1st_stage.MajorTickLabels = {'-60', '-45', '-30', '-15', '0', '15', '30', '45', '60'};
            app.XAxismisalignmentdegreesSlider_1st_stage.ValueChangedFcn = createCallbackFcn(app, @YAxismisalignmentdegreesSlider_1st_stageValueChanged, true);
            app.YAxismisalignmentdegreesSlider_1st_stage = uislider(app.Transmitter2satTab);
            app.YAxismisalignmentdegreesSlider_1st_stage.Limits = [-45 45];
            app.YAxismisalignmentdegreesSlider_1st_stage.MajorTicks = [-60 -45 -30 -15 0 15 30 45 60];
            app.YAxismisalignmentdegreesSlider_1st_stage.MajorTickLabels = {'-60', '-45', '-30', '-15', '0', '15', '30', '45', '60'};
            app.YAxismisalignmentdegreesSlider_1st_stage.ValueChangedFcn = createCallbackFcn(app, @YAxismisalignmentdegreesSlider_1st_stageValueChanged, true);
            app.Sat2receiverTab = uitab(app.two_stage_transmission);
            app.Sat2receiverTab.Title = 'Sat to receiver';
            app.Sat2ReceiverPlot = uiaxes(app.Sat2receiverTab);
            title(app.Sat2ReceiverPlot, '2nd stage misalignment (in km)')
            xlabel(app.Sat2ReceiverPlot, 'X Axis misalignment (degrees)')
            ylabel(app.Sat2ReceiverPlot, 'Y Axis misalignment (degrees)')
            zlabel(app.Sat2ReceiverPlot, 'Z')
            app.XAxismisalignmentdegreesSlider_2nd_stage = uislider(app.Sat2receiverTab);
            app.XAxismisalignmentdegreesSlider_2nd_stage.Limits = [-45 45];
            app.XAxismisalignmentdegreesSlider_2nd_stage.MajorTicks = [-60 -45 -30 -15 0 15 30 45 60];
            app.XAxismisalignmentdegreesSlider_2nd_stage.ValueChangedFcn = createCallbackFcn(app, @YAxismisalignmentdegreesSlider_2nd_stageValueChanged, true);
            app.YAxismisalignmentdegreesSlider_2nd_stage = uislider(app.Sat2receiverTab);
            app.YAxismisalignmentdegreesSlider_2nd_stage.Limits = [-45 45];
            app.YAxismisalignmentdegreesSlider_2nd_stage.MajorTicks = [-60 -45 -30 -15 0 15 30 45 60];
            app.YAxismisalignmentdegreesSlider_2nd_stage.ValueChangedFcn = createCallbackFcn(app, @YAxismisalignmentdegreesSlider_2nd_stageValueChanged, true);
            app.TransmitterReceiver = uitabgroup(app.TransmitterTab);
            app.Transmitter2Receiver = uitab(app.TransmitterReceiver);
            app.Transmitter2Receiver.Title = 'Transmitter to receiver';
            app.Transmitter2ReceiverPlot = uiaxes(app.Transmitter2Receiver);
            title(app.Transmitter2ReceiverPlot, 'Transmission misalignent (in km)')
            xlabel(app.Transmitter2ReceiverPlot, 'X Axis misalignment (degrees)')
            ylabel(app.Transmitter2ReceiverPlot, 'Y Axis misalignment (degrees)')
            zlabel(app.Transmitter2ReceiverPlot, 'Z')
            app.XAxismisalignmentdegreesSlider_default = uislider(app.Transmitter2Receiver);
            app.XAxismisalignmentdegreesSlider_default.Limits = [-45 45];
            app.XAxismisalignmentdegreesSlider_default.MajorTicks = [-60 -45 -30 -15 0 15 30 45 60];
            app.XAxismisalignmentdegreesSlider_default.ValueChangedFcn = createCallbackFcn(app, @YAxismisalignmentdegreesSlider_defaultValueChanged, true);
            app.YAxismisalignmentdegreesSlider_default = uislider(app.Transmitter2Receiver);
            app.YAxismisalignmentdegreesSlider_default.Limits = [-45 45];
            app.YAxismisalignmentdegreesSlider_default.MajorTicks = [-60 -45 -30 -15 0 15 30 45 60];
            app.YAxismisalignmentdegreesSlider_default.ValueChangedFcn = createCallbackFcn(app, @YAxismisalignmentdegreesSlider_defaultValueChanged, true);
            app.EntermessagetobetransmittedLabel = uilabel(app.TransmitterTab);
            app.EntermessagetobetransmittedLabel.Text = {'Enter message'; 'to be transmitted:'};
            app.TransmittedMessage = uieditfield(app.TransmitterTab, 'text');
            app.TransmittedMessage.Value = 'Never gonna give you up, never gonna let you down. Never gonna run around and desert you. Never gonna make you cry, never gonna say goodbye. Never gonna tell a lie and hurt you';
            app.WhereDropDownLabel = uilabel(app.TransmitterTab);
            app.WhereDropDownLabel.Text = 'Where?';
            app.WhereDropDown = uidropdown(app.TransmitterTab);
            app.WhereDropDown.Items = {'Ground only', 'Ground via sat relay', 'Cosmic Space from earth', 'Cosmic Space only'};
            app.WhereDropDown.ValueChangedFcn = createCallbackFcn(app, @WhereDropDownValueChanged, true);
            app.WhereDropDown.Value = 'Ground only';
            app.WavelengthnmEditFieldLabel = uilabel(app.TransmitterTab);
            app.WavelengthnmEditFieldLabel.Text = 'Wavelength (nm)';
            app.Wavelength = uieditfield(app.TransmitterTab, 'numeric');
            app.Wavelength.Value = 1550;
            app.LinkLengthkmEditFieldLabel = uilabel(app.TransmitterTab);
            app.LinkLengthkmEditFieldLabel.Text = 'Link Length (km)';
            app.LinkLengthkm = uieditfield(app.TransmitterTab, 'numeric');
            app.LinkLengthkm.ValueChangedFcn = createCallbackFcn(app, @LinkLengthkmValueChanged, true);
            app.LinkLengthkm.Value = 20;
            app.RequiredbitrateEditFieldLabel = uilabel(app.TransmitterTab);
            app.RequiredbitrateEditFieldLabel.Text = 'Required bit rate';
            app.BitRate = uieditfield(app.TransmitterTab, 'numeric');
            app.BitRate.Value = 200;
            app.BitRateUnits = uidropdown(app.TransmitterTab);
            app.BitRateUnits.Items = {'kbps', 'Mbps', 'Gbps', 'Tbps'};
            app.BitRateUnits.Value = 'kbps';
            app.ApperturecmLabel = uilabel(app.TransmitterTab);
            app.ApperturecmLabel.Text = 'Apperture (m)';
            app.Apperture = uieditfield(app.TransmitterTab, 'text');
            app.Apperture.Value = '0.2';
            app.BeamDivergenceLabel = uilabel(app.TransmitterTab);
            app.BeamDivergenceLabel.Text = 'Beam Divergence';
            app.Theta = uieditfield(app.TransmitterTab, 'numeric');
            app.Theta.Value = 0.002;
            app.ThetaUnits = uidropdown(app.TransmitterTab);
            app.ThetaUnits.Items = {'rad', 'degrees'};
            app.ThetaUnits.Value = 'rad';
            app.AtmosphericConditionsLabel = uilabel(app.TransmitterTab);
            app.AtmosphericConditionsLabel.Text = {'Atmospheric'; 'Conditions'};
            app.AtmosphericConditions = uidropdown(app.TransmitterTab);
            app.AtmosphericConditions.Items = {'Very clear air', 'Clear air', 'Very light mist', 'Light mist', 'Very light fog', 'Light fog', 'Moderate fog', 'Thick fog', 'Dense fog'};
            app.AtmosphericConditions.Value = 'Very clear air';
            app.ModulationDropDownLabel = uilabel(app.TransmitterTab);
            app.ModulationDropDownLabel.Text = 'Modulation ';
            app.Modulation = uidropdown(app.TransmitterTab);
            app.Modulation.Items = {'OOK', 'QPSK', '16 QAM', 'No modulation'};
            app.Modulation.Value = 'OOK';
            app.CalculateButton = uibutton(app.TransmitterTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate!';
            app.TransmitedPowermWLabel = uilabel(app.TransmitterTab);
            app.TransmitedPowermWLabel.Text = {'Average Transmited '; 'Power (W)'};
            app.AverageTransmittedPower = uieditfield(app.TransmitterTab, 'numeric');
            app.AverageTransmittedPower.Value = 5;
            app.ReceiverTab = uitab(app.TabGroup);
            app.ReceiverTab.Tooltip = {''};
            app.ReceiverTab.Title = 'Receiver';
            app.AtmosphericAttenuationEditFieldLabel = uilabel(app.ReceiverTab);
            app.AtmosphericAttenuationEditFieldLabel.Text = {'Atmospheric'; 'Attenuation (dB)'};
            app.AtmosphericAttenuation = uieditfield(app.ReceiverTab, 'numeric');
            app.GeometricalLossesdBLabel = uilabel(app.ReceiverTab);
            app.GeometricalLossesdBLabel.Text = 'Geometrical Losses (dB)';
            app.GeometricalLosses = uieditfield(app.ReceiverTab, 'numeric');
            app.PointingErrorsdBLabel = uilabel(app.ReceiverTab);
            app.PointingErrorsdBLabel.Text = 'Pointing Errors (dB)';
            app.PointingErrors = uieditfield(app.ReceiverTab, 'numeric');
            app.ReceivedbackLabel = uilabel(app.ReceiverTab);
            app.ReceivedbackLabel.Text = {'Received'; 'back'};
            app.Receivedback = uieditfield(app.ReceiverTab, 'text');
            app.Receivedback.Value = 'Input message to transmit in input tab';
            app.BEREditFieldLabel = uilabel(app.ReceiverTab);
            app.BEREditFieldLabel.Text = 'BER';
            app.BER = uieditfield(app.ReceiverTab, 'numeric');
            app.SNREditFieldLabel = uilabel(app.ReceiverTab);
            app.SNREditFieldLabel.Text = 'Receiver SNR (dB)';
            app.SNR = uieditfield(app.ReceiverTab, 'numeric');
            app.SNREditFieldLabel_2 = uilabel(app.ReceiverTab);
            app.SNREditFieldLabel_2.Text = 'Quality Factor';
            app.QualityFactor = uieditfield(app.ReceiverTab, 'numeric');
            app.BacktotransmitterButton = uibutton(app.ReceiverTab, 'push');
            app.BacktotransmitterButton.ButtonPushedFcn = createCallbackFcn(app, @BacktotransmitterButtonPushed, true);
            app.BacktotransmitterButton.Text = 'Back to transmitter';
            app.TabGroup3 = uitabgroup(app.ReceiverTab);
            app.BERvsSNRTab = uitab(app.TabGroup3);
            app.BERvsSNRTab.Title = 'BER vs SNR';
            app.BERvsSNR = uiaxes(app.BERvsSNRTab);
            title(app.BERvsSNR, 'Title')
            xlabel(app.BERvsSNR, 'X')
            ylabel(app.BERvsSNR, 'Y')
            zlabel(app.BERvsSNR, 'Z')
            app.BERvsQTab = uitab(app.TabGroup3);
            app.BERvsQTab.Title = 'BER vs Q';
            app.BERvsQ = uiaxes(app.BERvsQTab);
            title(app.BERvsQ, 'Title')
            xlabel(app.BERvsQ, 'X')
            ylabel(app.BERvsQ, 'Y')
            zlabel(app.BERvsQ, 'Z')
            app.CapacityvsBandwidthTab = uitab(app.TabGroup3);
            app.CapacityvsBandwidthTab.Title = 'Capacity vs Bandwidth';
            app.CAPACITYvsSNR = uiaxes(app.CapacityvsBandwidthTab);
            title(app.CAPACITYvsSNR, 'Title')
            xlabel(app.CAPACITYvsSNR, 'X')
            ylabel(app.CAPACITYvsSNR, 'Y')
            zlabel(app.CAPACITYvsSNR, 'Z')
            % Show the figure after all components are created
            app.FSOCSystemSimulatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FreeSpaceOpticalCommunicationsApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FSOCSystemSimulatorUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FSOCSystemSimulatorUIFigure)
        end
    end
end